classdef SonoScape < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SonoScapeUIFigure        matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        OpenMenu                 matlab.ui.container.Menu
        FolderMenu               matlab.ui.container.Menu
        SortMenu                 matlab.ui.container.Menu
        ByDayMenu                matlab.ui.container.Menu
        OptionsMenu              matlab.ui.container.Menu
        ExitMenu                 matlab.ui.container.Menu
        AnalyzeMenu              matlab.ui.container.Menu
        PatternRecognizeMenu     matlab.ui.container.Menu
        GraphMenu                matlab.ui.container.Menu
        ACIVisualizerMenu        matlab.ui.container.Menu
        EcoacousticEventsVisualizerTernaryPlotMenu  matlab.ui.container.Menu
        HelpMenu                 matlab.ui.container.Menu
        AboutMenu                matlab.ui.container.Menu
        GridLayout2              matlab.ui.container.GridLayout
        ExplorerLabel            matlab.ui.control.Label
        FileListLabel            matlab.ui.control.Label
        Tree                     matlab.ui.container.Tree
        ListBox                  matlab.ui.control.ListBox
        GridLayout8              matlab.ui.container.GridLayout
        AutoFolderButton         matlab.ui.control.Button
        GridLayout7              matlab.ui.container.GridLayout
        AutoButton               matlab.ui.control.Button
        AcousticComplexityAnalysisPanel  matlab.ui.container.Panel
        GridLayout               matlab.ui.container.GridLayout
        SSDAnalysisButton        matlab.ui.control.Button
        EcoacousticEventsButton  matlab.ui.control.Button
        NextButton               matlab.ui.control.Button
        WriteButton              matlab.ui.control.Button
        DoButton                 matlab.ui.control.Button
        ClumpPanel               matlab.ui.container.Panel
        GridLayout6              matlab.ui.container.GridLayout
        ClumpEditField           matlab.ui.control.NumericEditField
        FrequencyFilterPanel     matlab.ui.container.Panel
        GridLayout5              matlab.ui.container.GridLayout
        HighLabel_2              matlab.ui.control.Label
        HighFreqEditField        matlab.ui.control.NumericEditField
        LowEditFieldLabel_2      matlab.ui.control.Label
        LowFreqEditField         matlab.ui.control.NumericEditField
        EnergyFilterPanel        matlab.ui.container.Panel
        GridLayout4              matlab.ui.container.GridLayout
        LowLabel                 matlab.ui.control.Label
        LowEnergyEditField       matlab.ui.control.EditField
        HighEditFieldLabel       matlab.ui.control.Label
        HighEnergyEditField      matlab.ui.control.EditField
        ButtonGroup              matlab.ui.container.ButtonGroup
        NearfieldButton          matlab.ui.control.RadioButton
        FarfieldButton           matlab.ui.control.RadioButton
        TimeScalePanel           matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        secEditFieldLabel        matlab.ui.control.Label
        secEditField             matlab.ui.control.EditField
        StatusLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        toolbar
        upDirPushtool
        
        topFolder
        
        dataFolder % Description
        files
        
        currentIndex = 1
        currentFile
        dateTime = NaT
        
        signal
        Fs
        
        timescale    = 1
        energyFilter = struct('field', {'near'}, 'lowEnergy', 0, 'highEnergy', 0)
        freqFilter   = struct('lowFreq', 0, 'highFreq', inf)
        clump        = []
        
        myEco        = ecoacousticAnalysis
        myEcoEvent   = ecoacousticEvent
        
        % from config
        time = struct('format', {'HHmm-ddMMYYYY'}, 'value', [])
        save = struct('ACITotal', 1, 'ACIEvenness', 1, 'ACIMatrix', 1, 'ACIIntermediate', 0, 'EETernaryPlot', 0)
        asciiSep = '\t'
        compute = struct('ACIFtMax', {'adaptive'}, 'value', [])
        info    = struct('Temperature', 1)
        colSub  = struct('LongFileOpt', {'Subdivide'}, 'Per', 5);
        
        eeFailMark = 0
        eeAggregation  = struct('option', {'file'}, 'value', nan);
        
        asdAggregation = struct('option', {'file'}, 'value', nan);
        
        % state
        aciPerFile  = []
        aciAnalysis = 0
        eeAnalysis  = 0
        asdAnalysis = 0
        
        % structured output
        outTbl = []
    end
    
    methods (Access = private)
        
        function statusFeed(app, msg, varargin)
            app.StatusLabel.Text = msg;
            
            color = 'k';
            if nargin == 3
                if ~ischar(varargin{1}) && numel(varargin{1}) == 3
                    color = varargin{1};
                else
                    switch varargin{1}
                        case 'warn'
                            color = [1 .325 .286];
                        case {'error', 'r'}
                            color = 'r';
                        case 'reset'
                            color = 'k';
                        case 'g'
                            color = [.25 .88 .41];
                    end
                end
            end
            app.StatusLabel.FontColor = color;
            
            drawnow;
        end
        
        function eeEncoding(app)
            app.EcoacousticEventsButton.Text = "Encoding ...";
            app.EcoacousticEventsButton.Icon = 'encode.gif';
            app.EcoacousticEventsButton.IconAlignment = 'left';
            drawnow;
            
            searchPath = fullfile(app.dataFolder, "Results");
            numFolders = struct2table(dir(searchPath));
            numFolders(numFolders.isdir == 0, :) = [];
            
            if height(numFolders) <= 2
                app.statusFeed("No results found", "warn");
                app.EcoacousticEventsButton.Text = "EE Analysis";
                app.EcoacousticEventsButton.Icon = '';
                app.eeFailMark = 1;
                return;
            end
            
            % depending on whether ACIFtMax is read from adaptive results
            % or fixed values
            switch app.compute.ACIFtMax
                case 'adaptive'
                    app.statusFeed("searching global acitf max ...", "reset");
                    tempMaxSt = load(fullfile(searchPath, numFolders.name(3), 'cache.mat'));
                    tempMax   = tempMaxSt.aciTToMax__;
                    
                    for iN = 4:height(numFolders)
                        tempMaxSt = load(fullfile(searchPath, numFolders.name(iN), 'cache.mat'));
                        
                        try
                            tempMax = cellfun(@(x, y) max([x y], [], 2), tempMax, tempMaxSt.aciTToMax__, 'UniformOutput', 0);
                        catch
                            app.statusFeed("folder "+numFolders.name(iN)+" seems to be analyzed using different scales!", "error");
                            app.EcoacousticEventsButton.Text = "EE Analysis";
                            app.EcoacousticEventsButton.Icon = '';
                            app.eeFailMark = 1;
                            break;
                        end
                    end
                case 'fixed'
                    tempMax = arrayfun(@(x) x .* app.compute.value .* ones(1, 1, numel(app.energyFilter.lowEnergy)*numel(app.energyFilter.highEnergy)), ...
                        app.timescale, 'UniformOutput', false);
            end
            
            if ~app.eeFailMark
                app.statusFeed("encoding ...", "reset");
                for iN = 3:height(numFolders)
                    tempMaxSt = load(fullfile(searchPath, numFolders.name(iN), 'cache.mat'));
                    app.myEcoEvent.aciTTo       = tempMaxSt.aciTTo__;
                    app.myEcoEvent.aciTToMax    = tempMax;
                    app.myEcoEvent.aciTEvenness = tempMaxSt.aciTEvenness__;
                    app.myEcoEvent.aciFEvenness = tempMaxSt.aciFEvenness__;
                    
                    app.myEcoEvent.eeCalc;
                    app.myEcoEvent.eeWrite(fullfile(searchPath, numFolders.name(iN)));
                    app.myEcoEvent.eeWriteMat(fullfile(searchPath, numFolders.name(iN)));
                end
                app.statusFeed("Done encoding", 'reset');
            end
        end
        
        function eeAna(app)
            app.statusFeed("Performing ecoacoustic analysis", 'reset');
            app.EcoacousticEventsButton.Text = "Analyzing ...";
            app.EcoacousticEventsButton.IconAlignment = 'bottom';
            
            % create cdata for wait bar
            wbar = permute(repmat(app.EcoacousticEventsButton.BackgroundColor, 15, 1, 202), [1, 3, 2]);
            wbar([1 end], :, :) = 0;
            wbar(:, [1 end], :) = 0;
            app.EcoacousticEventsButton.Icon = wbar;
            
            drawnow;
            
            searchPath = fullfile(app.dataFolder, "Results");
            numFolders = dir(searchPath);
            numFolders(~[numFolders.isdir]) = [];
            
            allcats = string(reshape(sprintf('%03d', (0:999)')', 3, [])');
            
            % id for summary file
            app.statusFeed("Generating summary files ...", 'reset');
            
            fid_f = fopen(fullfile(searchPath, "ee_fractal.txt"), 'w');
            fmt_f = ['%f' app.asciiSep '%f' app.asciiSep '%s' app.asciiSep '%f\r\n'];
            fprintf(fid_f, replace(fmt_f, 'f', 's'), 'low_energy', 'high_energy', 'filename', 'fractal_dimension');
            
            % loop through filters
            [LOW, HIGH] = ndgrid(app.energyFilter.lowEnergy, app.energyFilter.highEnergy);
            filter = [LOW(:) HIGH(:)];
            for iF = 1:size(filter, 1)
                switch app.energyFilter.field
                    case 'near'
                        nameParts = "near_" + num2str(filter(iF, 2));
                    case 'far'
                        nameParts = "far_"  + num2str(filter(iF, 1)) + '_' + num2str(filter(iF, 2));
                end
                
                fid_h = fopen(fullfile(searchPath, ...
                    "ee_entropy_" + nameParts  + '.txt'), 'w');
                fmt   = repmat(['%d' app.asciiSep], 1, numel(app.timescale));
                fmt   = ['%s' app.asciiSep fmt(1:end-length(app.asciiSep)) '\r\n'];
                fprintf(fid_h, fmt, 'filename', app.timescale);
                
                for iS = 1:numel(app.timescale)
                    xlswrite(fullfile(searchPath, ...
                        "ee_" + nameParts + '.xlsx'), ...
                        ["filename"; "'"+allcats; "richness"]', ['Scale_' num2str(app.timescale(iS))], 'A1');
                end
                
                for iN = 3:numel(numFolders)
                    app.statusFeed("Analyzing "+numFolders(iN).name+" "+nameParts, 'reset');
                    tempEE = load(fullfile(searchPath, numFolders(iN).name, 'cache_ee.mat'));
                    
                    switch app.eeAggregation.option
                        case 'file'
                            freq = cellfun(@(x) eeCalc(x(:, :, iF), allcats), tempEE.ee__, 'UniformOutput', false);
                            
                            % back up freq to calculate distribution
                            if iN == 3
                                freq_dist = freq;
                            else
                                freq_dist = cellfun(@plus, freq_dist, freq, 'UniformOutput', false);
                            end
                            
                            h    = cellfun(@hCalc, freq);
                            
                            % write
                            fprintf(fid_h, replace(fmt, 'd', 'f'), numFolders(iN).name, h);
                            for iS = 1:numel(app.timescale)
                                fn = "ee_" + nameParts + '.xlsx';
                                xlswrite(fullfile(searchPath, fn), {numFolders(iN).name}, ['Scale_' num2str(app.timescale(iS))], ['A'   num2str(iN-1)]);
                                xlswrite(fullfile(searchPath, fn), freq{iS},              ['Scale_' num2str(app.timescale(iS))], ['B'   num2str(iN-1)]);
                                xlswrite(fullfile(searchPath, fn), sum(freq{iS} ~= 0),    ['Scale_' num2str(app.timescale(iS))], ['ALN' num2str(iN-1)]);
                                
                                % to structured output
                                fid = fopen(fullfile(app.topFolder, ...
                                    "EE_Richness_"+num2str(app.timescale(iS))+"_"+nameParts+".txt"), ...
                                    'a');
                                fprintf(fid, "%f"+app.asciiSep, sum(freq{iS} ~= 0));
                                fclose(fid);
                                
                                fid = fopen(fullfile(app.topFolder, ...
                                    "EE_Entropy_"+num2str(app.timescale(iS))+"_"+nameParts+".txt"), ...
                                    'a');
                                fprintf(fid, "%f"+app.asciiSep, h(iS));
                                fclose(fid);
                            end
                    end
                    
                    % fractal dimension
                    freq  = cellfun(@(x) fdCalc(x(:, :, iF)), tempEE.ee__, 'UniformOutput', true);
                    p     = polyfit(log10(app.timescale), log10(freq), 1);
                    alpha = 1 - p(1);
                    
                    fprintf(fid_f, fmt_f, filter(iF, 1), filter(iF, 2), numFolders(iN).name, alpha);
                    
                    prog = ceil(iF/size(filter, 1) * (iN-2)/(numel(numFolders)-2)*200)+1;
                    app.EcoacousticEventsButton.Icon(2:end-1, 2:prog, 1) = .25;
                    app.EcoacousticEventsButton.Icon(2:end-1, 2:prog, 2) = .88;
                    app.EcoacousticEventsButton.Icon(2:end-1, 2:prog, 3) = .41;
                    drawnow;
                end
                
                fclose(fid_h);
                
                % post process - ee_nameParts.xlsx - distribution -- plot
                for iS = 1:numel(app.timescale)
                    fn = "ee_" + nameParts + '.xlsx';
                    xlswrite(fullfile(searchPath, fn), {'distribution'}, ['Scale_' num2str(app.timescale(iS))], ['A' num2str(iN)]);
                    xlswrite(fullfile(searchPath, fn), freq_dist{iS},    ['Scale_' num2str(app.timescale(iS))], ['B' num2str(iN)]);
                    
                    % to structured output
                    fid = fopen(fullfile(app.topFolder, ...
                        "EE_Distribution_"+num2str(app.timescale(iS))+"_"+nameParts+".txt"), ...
                        'a');
                    fprintf(fid, "%f"+app.asciiSep, freq_dist{iS});
                    fprintf(fid, '\n');
                    fclose(fid);
                    
                    fid = fopen(fullfile(app.topFolder, ...
                        "EE_Richness_"+num2str(app.timescale(iS))+"_"+nameParts+".txt"), ...
                        'a');
                    fprintf(fid, '\n');
                    fclose(fid);
                    
                    % ternary plot request
                    if app.save.EETernaryPlot
                        
                        eeDist = freq_dist{iS};
                        aA     = floor((0:999)'/100);
                        aB     = floor((0:999)'/10) - aA*10;
                        aC     = (0:999)' - aA*100 - aB * 10;
                        tIn    = [aA aB aC eeDist'];
                        tIn(tIn(:, 4)==0, :) = [];
                        
                        apexa = tIn(:, 1) ./ (tIn(:, 1) + tIn(:, 2) + tIn(:, 3)) * 10;
                        apexb = tIn(:, 2) ./ (tIn(:, 1) + tIn(:, 2) + tIn(:, 3)) * 10;
                        apexc = tIn(:, 3) ./ (tIn(:, 1) + tIn(:, 2) + tIn(:, 3)) * 10;
                        apex  = [apexa apexb apexc];
                        [apexU, ~, indU] = unique(apex, 'rows', 'stable');
                        occu  = accumarray(indU, tIn(:, 4));
                        
                        % scale occu based on fixed max (200)
                        occuSize = (occu - min(occu)) ./ (max(occu) - min(occu)) .* 199 + 1;
                        
                        % plot
                        % adapt position
                        parentPos = get(app.SonoScapeUIFigure, 'Position');
                        Pos = [parentPos(1)+parentPos(3)/2 parentPos(2)+parentPos(4)-400 400 400];
                        
                        figPath = fullfile(app.dataFolder, "Figures");
                        if ~(exist(figPath, 'dir') == 7)
                            mkdir(figPath);
                        end
                        [~, currentFolder] = fileparts(fileparts(figPath));
                        eeFig = uifigure('Color', 'w', 'Units', 'pixels', 'Position', Pos, ...
                            'Name', "EE Ternary plot for "+currentFolder+"_"+num2str(app.timescale(iS))+"_"+nameParts, ...
                            'NumberTitle', 'off', 'Resize', 'off');
                        h = uiaxes(eeFig, 'Position', [10 10 380 380]);
                        ternary(apexU(:, 1), apexU(:, 2), apexU(:, 3), ...
                            'Occurance', occu, 'Size', occuSize, ...
                            'Parent', h, ...
                            'SizeScale', 'log', ...
                            'Label', {'ACI_{ft}', 'ACI_{ft, evenness}', 'ACI_{tf, evenness}'});
                        
                        % save
                        exportgraphics(h, fullfile(figPath, currentFolder+"_"+num2str(app.timescale(iS))+"_"+nameParts+".pdf"), 'ContentType', 'vector');
                        close(eeFig);
                    end
                end
            end
            fclose(fid_f);
            
            app.EcoacousticEventsButton.Text = "Finished";
            app.statusFeed("Done", 'reset');
            
            function freq = eeCalc(x, allcats)
                xCats = categorical(x, allcats);
                freq  = countcats(xCats);
            end
            
            function h = hCalc(x)
                x  = x./sum(x);
                x_ = x; x_(x_ == 0) = eps;
                h  = -sum(x.*log(x_));
            end
            
            function freq = fdCalc(x)
                freq = numel(unique(x));
            end
        end
        
        function resetButtonIcon(app)
            app.DoButton.Text = 'Process';
            app.DoButton.Icon = '';
            
            app.AutoButton.Text = 'AUTO';
            app.AutoButton.Icon = '';
            
            app.AutoFolderButton.Text = 'AUTO';
            app.AutoFolderButton.Icon = '';
            
            app.EcoacousticEventsButton.Text = 'Ecoacoustic Events';
            app.EcoacousticEventsButton.Icon = '';
            
            app.SSDAnalysisButton.Text = 'SSD Analysis';
            app.SSDAnalysisButton.Icon = '';
        end
        
        function openFolder(app)
            allfiles  = dir(fullfile(app.dataFolder, "*.wav"));
            
            if numel(allfiles) == 0
                app.statusFeed("no files found in the folder selected", "warn");
                app.ListBox.Items = {};
                app.files = [];
                app.resetButtonIcon;
                
                return;
            elseif numel(allfiles) == 1
                app.files = {struct2table(allfiles).name};
            else
                app.files = struct2table(allfiles).name;
            end
            
            app.ListBox.Items = app.files;
            app.ListBox.ItemsData = 1:length(app.files);
            app.ListBox.Value = 1;
            
            app.currentIndex  = 1;
            app.currentFile   = app.files{app.currentIndex};
            
            app.statusFeed("data loaded", "reset");
            app.aciPerFile  = zeros(length(app.files), 1);
            app.eeAnalysis  = 0;
            app.asdAnalysis = 0;
            
            app.resetButtonIcon;
        end
        
        function upDirPushtoolClicked(app, src, event)
            app.Tree.collapse;
            allNodes = app.Tree.Children;
            allNodes.delete;
            
            app.topFolder = fileparts(app.topFolder);
            
            % create tree
            dirNode(app.Tree, app.topFolder);
            
            % select the top layer by default
            app.Tree.SelectedNodes = app.Tree.Children(1);
            app.Tree.Children(1).Icon = 'folderOpened.gif';
            
            app.dataFolder = app.Tree.SelectedNodes.NodeData.Path;
            
            % call open
            app.openFolder;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % create toobar
            app.toolbar = uitoolbar(app.SonoScapeUIFigure);
            
            % top folder is current folder
            app.topFolder = cd;
            
            % folder up, since no transparency allowed, fake bk to bg color
            app.upDirPushtool = uipushtool(app.toolbar, 'Tooltip', 'up one level');
            img = double(imread('folder_dir_up.png'))/255;
            ib1 = img(:, :, 1) == 0;
            ib2 = img(:, :, 2) == 0;
            ib3 = img(:, :, 3) == 0;
            ibk = ib1 + ib2 + ib3 == 3;
            for i3 = 1:3
                rgb = img(:, :, i3);
                bkg = app.SonoScapeUIFigure.Color(i3);
                rgb(ibk) = bkg;
                img(:, :, i3) = rgb;
            end
            app.upDirPushtool.CData = img;
            app.upDirPushtool.ClickedCallback = @(source, event) upDirPushtoolClicked(app, source, event);
            
            % general setup
            app.myEco.timescale      = app.timescale;
            app.myEcoEvent.timescale = app.timescale;
            
            if exist('scConfig.mat', 'file') == 2
                load('scConfig.mat');
                
                app.time = timeConf;
                app.save = saveConf;
                app.asciiSep = asciiSepConf;
                app.colSub   = colSubConf;
            end
            
            % start from the current directory
            % create tree
            dirNode(app.Tree, cd);
            
            % select the top layer by default
            app.Tree.SelectedNodes = app.Tree.Children(1);
            app.Tree.Children(1).Icon = 'folderOpened.gif';
            
            % call open
            app.dataFolder = app.topFolder;
            app.openFolder;
        end

        % Menu selected function: FolderMenu
        function FolderMenuSelected(app, event)
            selpath = uigetdir(cd, "Select A Folder");
            if selpath == 0
                app.statusFeed("no folder selected", "warn");
                
                % clear tree and file lists
                app.Tree.Children.delete;
                app.ListBox.Items = {};
            else
                app.topFolder = selpath;
                
                % delete old trees
                app.Tree.Children.delete;
                
                % create tree
                dirNode(app.Tree, app.topFolder);
                
                % select the top layer by default
                app.Tree.SelectedNodes = app.Tree.Children(1);
                app.Tree.Children(1).Icon = 'folderOpened.gif';
                
                % call open
                app.dataFolder = app.topFolder;
                app.openFolder;
            end
            
            app.resetButtonIcon;
        end

        % Value changed function: secEditField
        function secEditFieldValueChanged(app, event)
            value = app.secEditField.Value;
            try
                app.timescale = eval(value);
                app.myEco.timescale      = app.timescale;
                app.myEcoEvent.timescale = app.timescale;
                app.statusFeed("Time scale defined", "reset");
            catch
                app.statusFeed("Invalid expression used for time scale!", "error");
            end
        end

        % Value changed function: LowEnergyEditField
        function LowEnergyEditFieldValueChanged(app, event)
            value = app.LowEnergyEditField.Value;
            try
                app.energyFilter.lowEnergy = eval(value);
                app.myEcoEvent.lowEnergy   = eval(value);
                app.statusFeed("Filter applied", "reset");
            catch
                app.statusFeed("Invalid expression used for low energy filter!", "error");
            end
        end

        % Value changed function: LowFreqEditField
        function LowFreqEditFieldValueChanged(app, event)
            value = app.LowFreqEditField.Value;
            app.freqFilter.lowFreq = value;
            app.statusFeed("Filter applied", "reset");
        end

        % Value changed function: ClumpEditField
        function ClumpEditFieldValueChanged(app, event)
            value = app.ClumpEditField.Value;
            if value == 0
                app.clump = [];
            else
                app.clump = value;
            end
            app.statusFeed("Clump applied", "reset");
        end

        % Button pushed function: DoButton
        function DoButtonPushed(app, event)
            app.DoButton.Text = 'Processing ...';
            app.DoButton.IconAlignment = 'bottom';
            
            % create cdata for wait bar
            wbar = permute(repmat(app.DoButton.BackgroundColor, 15, 1, 202), [1, 3, 2]);
            wbar([1 end], :, :) = 0;
            wbar(:, [1 end], :) = 0;
            app.DoButton.Icon = wbar;
            
            % Process
            % Parse data
            if isempty(app.files)
                return;
            end
            
            if app.currentIndex > length(app.files)
                app.statusFeed("Already hit the last file; job's done", "reset");
                return;
            end
            
            [app.signal, app.Fs] = audioread(fullfile(app.dataFolder, app.currentFile));
            drawnow;
            
            % parse time
            [datetimestr, format] = parsetime(app.currentFile, app.time.format);
            try
                app.dateTime = datetime(datetimestr, "InputFormat", format);
            catch
                app.dateTime = NaT;
            end
            
            % parse temperature
            if app.info.Temperature
                commentInfo = audioinfo(fullfile(app.dataFolder, app.currentFile));
                if ~isempty(commentInfo.Comment)
                    structComm  = regexp(commentInfo.Comment, ...
                        '(?<Keywords>(?:temperature was\s*))(?<Value>\d+\.?\d+)(?<Unit>\w?)', 'names');
                    
                    if ~isempty(structComm.Value)
                        fid = fopen(fullfile(app.dataFolder, 'temperature.txt'), 'a+');
                        fprintf(fid, "%s"+app.asciiSep+...
                            "%d"+app.asciiSep+"%d"+app.asciiSep+"%d"+app.asciiSep+...
                            "%d"+app.asciiSep+"%d"+app.asciiSep+"%d"+app.asciiSep+...
                            "%s"+app.asciiSep+"%s\n", ...
                            app.currentFile, ...
                            year(app.dateTime), month(app.dateTime), day(app.dateTime), ...
                            hour(app.dateTime), minute(app.dateTime), second(app.dateTime), ...
                            structComm.Value, structComm.Unit);
                        fclose(fid);
                    end
                end
            end
            
            % progression 10%
            app.statusFeed("Data loaded", "reset");
            app.DoButton.Icon(2:end-1, 2:21, 1) = .25;
            app.DoButton.Icon(2:end-1, 2:21, 2) = .88;
            app.DoButton.Icon(2:end-1, 2:21, 3) = .41;
            drawnow;
            
            app.myEco.ts = app.signal;
            app.myEco.fs = app.Fs;
            
            app.myEco.window       = hann(1024);
            app.myEco.windowLength = 1024;
            
            app.myEco.acousticComplexity = struct(...
                'energyFilter',   app.energyFilter, ...
                'freqFilter',     app.freqFilter, ...
                'clumping',       app.clump);
            
            % progression 40%
            app.statusFeed("Wait ...", "reset");
            drawnow;
            tsRescale = app.myEco.rescale;
            app.myEco.stft(tsRescale);
            app.statusFeed("STFT done", "reset");
            app.DoButton.Icon(2:end-1, 22:81, 1) = .25;
            app.DoButton.Icon(2:end-1, 22:81, 2) = .88;
            app.DoButton.Icon(2:end-1, 22:81, 3) = .41;
            drawnow;
            
            % progression 60%
            app.myEco.aci;
            app.statusFeed("ACI done", "reset");
            app.DoButton.Icon(2:end-1, 82:121, 1) = .25;
            app.DoButton.Icon(2:end-1, 82:121, 2) = .88;
            app.DoButton.Icon(2:end-1, 82:121, 3) = .41;
            drawnow;
            
            % progression 100%
            app.myEco.aciEvenness;
            app.myEco.aciTotal
            app.statusFeed("ACI evenness done", "reset");
            app.DoButton.Icon(2:end-1, 122:end-1, 1) = .25;
            app.DoButton.Icon(2:end-1, 122:end-1, 2) = .88;
            app.DoButton.Icon(2:end-1, 122:end-1, 3) = .41;
            drawnow;
            
            % temp max -- calc anyway no matter adaptive or fixed ACIFtMax
            app.myEco.aciTotalMax;
            
            % After process
            app.DoButton.Text = 'Done';
            drawnow;
            
            % analysis state
            app.aciPerFile(app.currentIndex) = 1;
            if all(app.aciPerFile)
                app.aciAnalysis = 1;
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            bkclr = app.NextButton.BackgroundColor;
            app.NextButton.BackgroundColor = [1 1 1];
            if app.currentIndex < length(app.files)
                app.currentIndex  = app.currentIndex + 1;
                app.ListBox.Value = app.currentIndex;
                app.currentFile   = app.files{app.currentIndex};
                
                app.DoButton.Icon = '';
                app.DoButton.Text = 'Process';
                app.statusFeed("File selected", "reset");
            else
                app.currentIndex = length(app.files) + 1;
                app.statusFeed("No more files", "warn");
            end
            app.NextButton.BackgroundColor = bkclr;
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.currentIndex = value;
            app.currentFile  = app.files{app.currentIndex};
            app.statusFeed("File selected", "reset");
            
            app.resetButtonIcon;
            
            % parse prior runs
            if exist(fullfile(app.dataFolder, 'Results', app.currentFile(1:end-4), 'cache.mat'), 'file') == 2
                app.aciPerFile(app.currentIndex) = 1;
            end
        end

        % Button pushed function: WriteButton
        function WriteButtonPushed(app, event)
            bkclr = app.WriteButton.BackgroundColor;
            app.WriteButton.BackgroundColor = [1 1 1];
            if isempty(app.myEco.aciFTo)
                app.statusFeed("Nothing to save", 'warn');
                return;
            end
            
            app.statusFeed("Saving results ...", "reset");
            drawnow;
            
            savePt = fullfile(app.dataFolder, "Results", app.currentFile(1:end-4));
            if ~(exist(savePt, 'dir') == 7)
                mkdir(savePt);
            end
            
            app.myEco.aciWriteSeperate(savePt, app.asciiSep);
            
            switch app.compute.ACIFtMax
                case 'adaptive'
                    % store max value adaptively -- folder specific
                    app.myEco.aciWriteMax(savePt, app.asciiSep);
            end
            
            app.myEco.aciWriteMat(savePt);

            switch app.colSub.LongFileOpt
                case 'Collate'
                    app.myEco.aciWriteACIMatrix(savePt, app.asciiSep);
                case 'Subdivide'
                    app.myEco.aciWriteACIMatrix(savePt, app.asciiSep, app.colSub.Per);
            end
            
            % save intermediate
            if app.save.ACIIntermediate
                app.myEco.aciWriteIntermediate(savePt, app.asciiSep);
            end
            
            app.statusFeed("Results saved", "reset");
            app.WriteButton.BackgroundColor = bkclr;
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            % QC
            if isempty(app.files)
                % create structured output files
                nameLeading = ["ACItf", "ACIft", "EE_Distribution", "EE_Richness", "EE_Entropy", "SSD"];
                [LOW, HIGH] = ndgrid(app.energyFilter.lowEnergy, app.energyFilter.highEnergy);
                filter = [LOW(:) HIGH(:)];
                for iF = 1:size(filter, 1)
                    switch app.energyFilter.field
                        case 'near'
                            nameParts = "_near_" + num2str(filter(iF, 2));
                        case 'far'
                            nameParts = "_far_"  + num2str(filter(iF, 1)) + '_' + num2str(filter(iF, 2));
                    end
                    
                    for iS = 1:length(app.timescale)
                        for iN = 1:numel(nameLeading)
                            fid = fopen(fullfile(app.topFolder, ...
                                nameLeading(iN)+"_"+num2str(app.timescale(iS))+nameParts+".txt"), ...
                                'a');
                            fprintf(fid, '\n');
                            fclose(fid);
                        end
                    end
                end
                
                return;
            end
            
            app.AutoButton.Icon = 'spinner.gif';
            app.AutoButton.IconAlignment = 'bottom';
            drawnow;
            
            % back up Results
            endStr = datestr(now, 'yyyymmddHHMMss');
            oldRes = fullfile(app.dataFolder, "Results");
            bacRes = fullfile(app.dataFolder, "Results_"+endStr);
            if exist(oldRes, "dir") == 7
                app.statusFeed("Backing up previous analyses", "warn");
                try
                    movefile(oldRes, bacRes);
                catch
                    try
                        fclose("all");
                        movefile(oldRes, bacRes);
                    catch
                        app.statusFeed('Please close the files you opened from Results folder and try again', 'error');
                        app.resetButtonIcon;
                        return;
                    end
                end
            end
            
            % back up Figures
            oldRes = fullfile(app.dataFolder, "Figures");
            bacRes = fullfile(app.dataFolder, "Figures_"+endStr);
            if exist(oldRes, "dir") == 7
                app.statusFeed("Backing up previous analyses", "warn");
                try
                    movefile(oldRes, bacRes);
                catch
                    try
                        fclose("all");
                        movefile(oldRes, bacRes);
                    catch
                        app.statusFeed('Please close the files you opened from Results folder and try again', 'error');
                        app.resetButtonIcon;
                        return;
                    end
                end
            end
            
            for iF = app.currentIndex:length(app.files)
                app.DoButtonPushed;
                app.WriteButtonPushed;
                app.NextButtonPushed;
            end
            
            % post write -- calc ACITf_TTot sum across all files
            searchPath = fullfile(app.dataFolder, "Results");
            numFolders = dir(searchPath);
            numFolders(~[numFolders.isdir]) = [];
            
            app.statusFeed("Calculating Acoustic Signinature");
            
            for iN = 3:numel(numFolders)
                tempMat = load(fullfile(searchPath, numFolders(iN).name, 'cache.mat'));
                
                acitf_tot = tempMat.aciFTot__;
                
                if iN == 3
                    acitf_tot_sum = acitf_tot;
                    acift_to_sum  = tempMat.aciTToSum__;
                else
                    acitf_tot_sum = cellfun(@plus, acitf_tot_sum, acitf_tot, 'UniformOutput', false);
                    acift_to_sum  = cellfun(@(x, y) cat(2, x, y), acift_to_sum, tempMat.aciTToSum__, 'UniformOutput', false);
                end
            end
            
            % loop through filters
            [LOW, HIGH] = ndgrid(app.energyFilter.lowEnergy, app.energyFilter.highEnergy);
            filter = [LOW(:) HIGH(:)];
            for iF = 1:size(filter, 1)
                switch app.energyFilter.field
                    case 'near'
                        nameParts = "_near_" + num2str(filter(iF, 2));
                    case 'far'
                        nameParts = "_far_"  + num2str(filter(iF, 1)) + '_' + num2str(filter(iF, 2));
                end
                
                for iS = 1:length(app.timescale)
                    filept = fullfile(searchPath, ...
                        "ACITf_TTot_" + num2str(app.timescale(iS)) + nameParts +".txt");
                    writematrix([nan acitf_tot_sum{iS}(:, :, iF)'], ...
                        filept, 'Delimiter', app.asciiSep, 'WriteMode', 'append'); % leading nan overwrite the filename column
                    
                    % to structured output files
                    fid = fopen(fullfile(app.topFolder, ...
                        "ACItf_"+num2str(app.timescale(iS))+nameParts+".txt"), ...
                        'a');
                    fprintf(fid, "%f"+app.asciiSep, acitf_tot_sum{iS}(:, :, iF)');
                    fprintf(fid, "\n");
                    fclose(fid);
                    
                    fid = fopen(fullfile(app.topFolder, ...
                        "ACIft_"+num2str(app.timescale(iS))+nameParts+".txt"), ...
                        'a');
                    fprintf(fid, "%f"+app.asciiSep, acift_to_sum{iS}(:, :, iF));
                    fprintf(fid, "\n");
                    fclose(fid);
                end
            end
            
            app.aciAnalysis = 1;
            
            app.EcoacousticEventsButtonPushed;
            app.SSDAnalysisButtonPushed;
            
            app.statusFeed("Done", "reset");
            app.AutoButton.Icon = '';
        end

        % Button pushed function: EcoacousticEventsButton
        function EcoacousticEventsButtonPushed(app, event)
            app.eeFailMark = 0;
            app.eeEncoding;
            if app.eeFailMark
                return;
            end
            app.eeAna;
            
            app.eeAnalysis = 1;
        end

        % Menu selected function: OptionsMenu
        function OptionsMenuSelected(app, event)
            called = optionWindow(app.time, app.save, app.asciiSep, app.compute, app.info, app.colSub);
            app.statusFeed('Waiting for configuration ...');
            uiwait(called.WizardFigure)
            if called.imported
                app.statusFeed("Configuration changed");
                app.time = called.time;
                app.save = called.save;
                app.asciiSep = called.asciiSep;
                app.compute  = called.compute;
                app.info     = called.info;
                app.colSub   = called.colSub;
            else
                app.statusFeed('Nothing changed.');
            end
            
            called.delete;
        end

        % Button pushed function: SSDAnalysisButton
        function SSDAnalysisButtonPushed(app, event)
            app.statusFeed("Performing acoustic signiture dissimilarity analysis", 'reset');
            app.SSDAnalysisButton.Text = "Analyzing ...";
            app.SSDAnalysisButton.IconAlignment = 'bottom';
            
            % create cdata for wait bar
            wbar = permute(repmat(app.SSDAnalysisButton.BackgroundColor, 15, 1, 202), [1, 3, 2]);
            wbar([1 end], :, :) = 0;
            wbar(:, [1 end], :) = 0;
            app.SSDAnalysisButton.Icon = wbar;
            
            drawnow;
            
            searchPath = fullfile(app.dataFolder, "Results");
            numFolders = dir(searchPath);
            numFolders(~[numFolders.isdir]) = [];
            
            % id for summary file
            app.statusFeed("Generating summary files ...", 'reset');
            
            fid_f = fopen(fullfile(searchPath, "ssd_fractal.txt"), 'w');
            fmt_f = ['%f' app.asciiSep '%f' app.asciiSep '%s' app.asciiSep '%f\r\n'];
            fprintf(fid_f, replace(fmt_f, 'f', 's'), 'low_energy', 'high_energy', 'filename', 'fractal_dimension');
            
            % loop through filters
            [LOW, HIGH] = ndgrid(app.energyFilter.lowEnergy, app.energyFilter.highEnergy);
            filter = [LOW(:) HIGH(:)];
            for iF = 1:size(filter, 1)
                switch app.energyFilter.field
                    case 'near'
                        nameParts = "near_" + num2str(filter(iF, 2));
                    case 'far'
                        nameParts = "far_"  + num2str(filter(iF, 1)) + '_' + num2str(filter(iF, 2));
                end
                
                fid_h = fopen(fullfile(searchPath, 'ssd_'+nameParts+'.txt'), 'w');
                fmt   = repmat(['%d' app.asciiSep], 1, numel(app.timescale));
                fmt   = ['%s' app.asciiSep fmt(1:end-length(app.asciiSep)) '\r\n'];
                fprintf(fid_h, fmt, 'filename', app.timescale);
                
                for iN = 3:numel(numFolders)
                    app.statusFeed("Analyzing "+numFolders(iN).name+" "+nameParts, 'reset');
                    
                    temp = load(fullfile(searchPath, numFolders(iN).name, 'cache.mat'));
                    
                    asd  = nan(size(app.timescale));
                    for iS = 1:numel(app.timescale)
                        acitf = temp.aciF__{iS}(:, :, iF);
                        
                        switch app.asdAggregation.option
                            case 'file'
                                d = pdist(acitf', @dchord);
                                asd(iS) = sum(d, 'omitnan')./length(d);
                        end
                        
                        % to structured output
                        fid = fopen(fullfile(app.topFolder, ...
                            "SSD_"+num2str(app.timescale(iS))+"_"+nameParts+".txt"), ...
                            'a');
                        fprintf(fid, "%f"+app.asciiSep, asd(iS));
                        fclose(fid);
                    end
                    
                    % write
                    fprintf(fid_h, replace(fmt, 'd', 'f'), numFolders(iN).name, asd);
                    
                    % fractal dimension
                    p     = polyfit(log10(app.timescale), log10(asd), 1);
                    alpha = 1 - p(1);
                    
                    fprintf(fid_f, fmt_f, filter(iF, 1), filter(iF, 2), numFolders(iN).name, alpha);
                    
                    prog = ceil(iF/size(filter, 1) * (iN-2)/(numel(numFolders)-2)*200)+1;
                    app.SSDAnalysisButton.Icon(2:end-1, 2:prog, 1) = .25;
                    app.SSDAnalysisButton.Icon(2:end-1, 2:prog, 2) = .88;
                    app.SSDAnalysisButton.Icon(2:end-1, 2:prog, 3) = .41;
                    drawnow;
                end
                
                % to structured output
                for iS = 1:numel(app.timescale)
                    fid = fopen(fullfile(app.topFolder, ...
                        "SSD_"+num2str(app.timescale(iS))+"_"+nameParts+".txt"), ...
                        'a');
                    fprintf(fid, '\n');
                    fclose(fid);
                end
            end
            app.SSDAnalysisButton.Text = "Finished";
            app.statusFeed("Done", 'reset');
            
            fclose(fid_h);
            fclose(fid_f);
            
            app.asdAnalysis = 1;
            
            function d = dchord(XI, XJ)
                d = sqrt(sum((XI./norm(XI) - XJ./repmat(vecnorm(XJ, 2, 2), 1, size(XJ, 2))).^2, 2));
            end
        end

        % Menu selected function: ACIVisualizerMenu
        function ACIVisualizerMenuSelected(app, event)
            if app.aciAnalysis && app.eeAnalysis && app.asdAnalysis
                aciResultControl(app.timescale, ...
                    app.energyFilter.field, app.energyFilter.lowEnergy, app.energyFilter.highEnergy, ...
                    app.currentFile(1:end-4), fullfile(app.dataFolder, "Results"));
            end
        end

        % Menu selected function: PatternRecognizeMenu
        function PatternRecognizeMenuSelected(app, event)
            if app.aciPerFile(app.currentIndex)
                patternRecognize(app.currentFile, app.dataFolder);
            end
        end

        % Menu selected function: ByDayMenu
        function ByDayMenuSelected(app, event)
            selpath = uigetdir(fullfile(cd, "*.wav"), "Select Folder Containing Data");
            if selpath == 0
                app.statusFeed("no folder selected", "warn");
            else
                allfiles = dir(fullfile(selpath, "*.wav"));
                
                if numel(allfiles) == 0
                    app.statusFeed("no files found in the folder selected", "warn");
                    return;
                elseif numel(allfiles) == 1
                    fileNames = {struct2table(allfiles).name};
                else
                    fileNames = struct2table(allfiles).name;
                end
                
                app.statusFeed("moving "+num2str(numel(fileNames))+" files", "reset");
                drawnow;
                
                % move files
                failed = 0;
                for iF = 1:numel(fileNames)
                    [datetimestr, format] = parsetime(fileNames{iF}, app.time.format);
                    
                    try
                        fileDateTime = datetime(datetimestr, "InputFormat", format);
                        
                        folderName   = [num2str(fileDateTime.Year) sprintf('%02d', fileDateTime.Month) sprintf('%02d', fileDateTime.Day)];
                        if ~(exist(fullfile(selpath, folderName), 'dir') == 7)
                            mkdir(fullfile(selpath, folderName));
                        end
                        movefile(fullfile(selpath, fileNames{iF}), fullfile(selpath, folderName));
                    catch
                        failed = failed + 1;
                    end
                end
                
                app.statusFeed("moved "+sprintf('%d', numel(fileNames)-failed)+" out of "+sprintf('%d', numel(fileNames))+" files", "reset");
            end
            
            app.resetButtonIcon;
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            app.LowEnergyEditField.Value = '0';
            app.energyFilter.lowEnergy   = 0;
            app.myEcoEvent.lowEnergy     = 0;
            
            selectedButton = app.ButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'Near field'
                    app.LowEnergyEditField.Enable  = 'off';
                    app.LowLabel.Enable            = 'off';
                    app.energyFilter.field         = 'near';
                    app.myEcoEvent.field           = 'near';
                    
                    app.HighEnergyEditField.Value  = '0';
                    app.energyFilter.highEnergy    = 0;
                    app.myEcoEvent.highEnergy      = 0;
                case 'Far field'
                    app.LowEnergyEditField.Enable  = 'on';
                    app.LowLabel.Enable            = 'on';
                    app.energyFilter.field         = 'far';
                    app.myEcoEvent.field           = 'far';
                    
                    app.HighEnergyEditField.Value  = 'inf';
                    app.energyFilter.highEnergy    = inf;
                    app.myEcoEvent.highEnergy      = inf;
            end
            
            app.statusFeed("Field applied", "reset");
        end

        % Value changed function: HighEnergyEditField
        function HighEnergyEditFieldValueChanged(app, event)
            value = app.HighEnergyEditField.Value;
            try
                app.energyFilter.highEnergy = eval(value);
                app.myEcoEvent.highEnergy   = eval(value);
                app.statusFeed("Filter applied", "reset");
            catch
                app.statusFeed("Invalid expression used for high energy filter!", "error");
            end
        end

        % Value changed function: HighFreqEditField
        function HighFreqEditFieldValueChanged(app, event)
            value = app.HighFreqEditField.Value;
            if value ~= 0
                app.freqFilter.highFreq = value;
            else
                app.freqFilter.highFreq = inf;
            end
            app.statusFeed("Filter applied", "reset");
        end

        % Callback function: ExitMenu, SonoScapeUIFigure
        function SonoScapeUIFigureCloseRequest(app, event)
            delete(app);
            
            % collect all child
            allShow = allchild(0);
            delete(allShow);
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            about;
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            selectedNodes.Icon = 'folderOpened.gif';
            event.PreviousSelectedNodes.Icon = 'folderClosed.gif';
            
            % parse directory
            app.dataFolder = selectedNodes.NodeData.Path;
            app.openFolder;
        end

        % Node expanded function: Tree
        function TreeNodeExpanded(app, event)
            node = event.Node;
            intoFolder(node.NodeData.ChildNodes, node.NodeData.SubFolders, 0);
        end

        % Button pushed function: AutoFolderButton
        function AutoFolderButtonPushed(app, event)
            currentDirNode = app.Tree.SelectedNodes;
            
            app.dataFolder = currentDirNode.NodeData.Path;
            app.openFolder;
            
            % create structured output files
            nameLeading = ["ACItf", "ACIft", "EE_Distribution", "EE_Richness", "EE_Entropy", "SSD"];
            [LOW, HIGH] = ndgrid(app.energyFilter.lowEnergy, app.energyFilter.highEnergy);
            filter = [LOW(:) HIGH(:)];
            for iF = 1:size(filter, 1)
                switch app.energyFilter.field
                    case 'near'
                        nameParts = "_near_" + num2str(filter(iF, 2));
                    case 'far'
                        nameParts = "_far_"  + num2str(filter(iF, 1)) + '_' + num2str(filter(iF, 2));
                end
                
                for iS = 1:length(app.timescale)
                    for iN = 1:numel(nameLeading)
                        fid = fopen(fullfile(app.topFolder, ...
                            nameLeading(iN)+"_"+num2str(app.timescale(iS))+nameParts+".txt"), ...
                            'a');
                        fprintf(fid, "%s"+app.asciiSep, currentDirNode.NodeData.Layer);
                        fclose(fid);
                    end
                end
            end
            
            % auto run
            app.AutoFolderButton.Icon = 'spinner.gif';
            app.AutoFolderButton.IconAlignment = 'bottom';
            drawnow;
            
            app.AutoButtonPushed;
            
            if isempty(currentDirNode.NodeData.SubFolders)
                app.resetButtonIcon;
                
                % done
                app.statusFeed('MISSION DONE!', 'g')
                
                return;
            end
            
            currentDirNode.expand;
            intoFolder(currentDirNode.NodeData.ChildNodes, currentDirNode.NodeData.SubFolders, 0);
            
            % recursively goes into each subfolder
            for iF = 1:numel(currentDirNode.NodeData.ChildNodes)
                app.Tree.SelectedNodes = currentDirNode.NodeData.ChildNodes(iF);
                app.Tree.scroll(app.Tree.SelectedNodes);
                
                app.AutoFolderButtonPushed;
            end
            
            % done
            app.statusFeed('MISSION DONE!', 'g')
        end

        % Menu selected function: 
        % EcoacousticEventsVisualizerTernaryPlotMenu
        function EcoacousticEventsVisualizerTernaryPlotMenuSelected(app, event)
            if app.aciAnalysis && app.eeAnalysis && app.asdAnalysis
                aciResultControl(app.timescale, ...
                    app.energyFilter.field, app.energyFilter.lowEnergy, app.energyFilter.highEnergy, ...
                    [], fullfile(app.dataFolder, "Results"), 'EE');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SonoScapeUIFigure = uifigure('Visible', 'off');
            app.SonoScapeUIFigure.Name = 'SonoScape';
            app.SonoScapeUIFigure.CloseRequestFcn = createCallbackFcn(app, @SonoScapeUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.SonoScapeUIFigure);
            app.FileMenu.Text = '&File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open';
            app.FolderMenu = uimenu(app.OpenMenu);
            app.FolderMenu.MenuSelectedFcn = createCallbackFcn(app, @FolderMenuSelected, true);
            app.FolderMenu.Text = 'Folder';
            app.SortMenu = uimenu(app.FileMenu);
            app.SortMenu.Separator = 'on';
            app.SortMenu.Text = 'Sort';
            app.ByDayMenu = uimenu(app.SortMenu);
            app.ByDayMenu.MenuSelectedFcn = createCallbackFcn(app, @ByDayMenuSelected, true);
            app.ByDayMenu.Text = 'By Day';
            app.OptionsMenu = uimenu(app.FileMenu);
            app.OptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @OptionsMenuSelected, true);
            app.OptionsMenu.Separator = 'on';
            app.OptionsMenu.Accelerator = 'O';
            app.OptionsMenu.Text = 'Options';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @SonoScapeUIFigureCloseRequest, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Accelerator = 'Q';
            app.ExitMenu.Text = 'Exit';
            app.AnalyzeMenu = uimenu(app.SonoScapeUIFigure);
            app.AnalyzeMenu.Text = '&Analyze';
            app.PatternRecognizeMenu = uimenu(app.AnalyzeMenu);
            app.PatternRecognizeMenu.MenuSelectedFcn = createCallbackFcn(app, @PatternRecognizeMenuSelected, true);
            app.PatternRecognizeMenu.Accelerator = 'R';
            app.PatternRecognizeMenu.Text = 'Pattern Recognize';
            app.GraphMenu = uimenu(app.SonoScapeUIFigure);
            app.GraphMenu.Text = '&Graph';
            app.ACIVisualizerMenu = uimenu(app.GraphMenu);
            app.ACIVisualizerMenu.MenuSelectedFcn = createCallbackFcn(app, @ACIVisualizerMenuSelected, true);
            app.ACIVisualizerMenu.Accelerator = 'L';
            app.ACIVisualizerMenu.Text = 'ACI Visualizer';
            app.EcoacousticEventsVisualizerTernaryPlotMenu = uimenu(app.GraphMenu);
            app.EcoacousticEventsVisualizerTernaryPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @EcoacousticEventsVisualizerTernaryPlotMenuSelected, true);
            app.EcoacousticEventsVisualizerTernaryPlotMenu.Separator = 'on';
            app.EcoacousticEventsVisualizerTernaryPlotMenu.Accelerator = 'R';
            app.EcoacousticEventsVisualizerTernaryPlotMenu.Text = 'Ecoacoustic Events Visualizer (Ternary Plot)';
            app.HelpMenu = uimenu(app.SonoScapeUIFigure);
            app.HelpMenu.Text = '&Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Accelerator = 'A';
            app.AboutMenu.Text = 'About';
            app.GridLayout2 = uigridlayout(app.SonoScapeUIFigure);
            app.StatusLabel = uilabel(app.GridLayout2);
            app.StatusLabel.Text = 'Status';
            app.TimeScalePanel = uipanel(app.GridLayout2);
            app.TimeScalePanel.Title = 'Time Scale';
            app.GridLayout3 = uigridlayout(app.TimeScalePanel);
            app.secEditField = uieditfield(app.GridLayout3, 'text');
            app.secEditField.ValueChangedFcn = createCallbackFcn(app, @secEditFieldValueChanged, true);
            app.secEditField.Tooltip = {'Syntaxed expression allowed. E.g., 1:5, [1:5, 10:10:100]'};
            app.secEditField.Value = '1';
            app.secEditFieldLabel = uilabel(app.GridLayout3);
            app.secEditFieldLabel.Text = 'sec';
            app.EnergyFilterPanel = uipanel(app.GridLayout2);
            app.EnergyFilterPanel.Title = 'Energy Filter';
            app.GridLayout4 = uigridlayout(app.EnergyFilterPanel);
            app.ButtonGroup = uibuttongroup(app.GridLayout4);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.FarfieldButton = uiradiobutton(app.ButtonGroup);
            app.FarfieldButton.Text = 'Far field';
            app.NearfieldButton = uiradiobutton(app.ButtonGroup);
            app.NearfieldButton.Text = 'Near field';
            app.NearfieldButton.Value = true;
            app.HighEnergyEditField = uieditfield(app.GridLayout4, 'text');
            app.HighEnergyEditField.ValueChangedFcn = createCallbackFcn(app, @HighEnergyEditFieldValueChanged, true);
            app.HighEnergyEditField.Tooltip = {'Syntaxed expression allowed. E.g., 1:5, [1:5, 10:10:100]'};
            app.HighEnergyEditField.Value = '0';
            app.HighEditFieldLabel = uilabel(app.GridLayout4);
            app.HighEditFieldLabel.Text = 'High:';
            app.LowEnergyEditField = uieditfield(app.GridLayout4, 'text');
            app.LowEnergyEditField.ValueChangedFcn = createCallbackFcn(app, @LowEnergyEditFieldValueChanged, true);
            app.LowEnergyEditField.Enable = 'off';
            app.LowEnergyEditField.Tooltip = {'Syntaxed expression allowed. E.g., 1:5, [1:5, 10:10:100]'};
            app.LowEnergyEditField.Value = '0';
            app.LowLabel = uilabel(app.GridLayout4);
            app.LowLabel.Enable = 'off';
            app.LowLabel.Text = 'Low:';
            app.FrequencyFilterPanel = uipanel(app.GridLayout2);
            app.FrequencyFilterPanel.Title = 'Frequency Filter';
            app.GridLayout5 = uigridlayout(app.FrequencyFilterPanel);
            app.LowFreqEditField = uieditfield(app.GridLayout5, 'numeric');
            app.LowFreqEditField.Limits = [0 Inf];
            app.LowFreqEditField.ValueChangedFcn = createCallbackFcn(app, @LowFreqEditFieldValueChanged, true);
            app.LowFreqEditField.Tooltip = {'Only allow single numerical input'};
            app.LowEditFieldLabel_2 = uilabel(app.GridLayout5);
            app.LowEditFieldLabel_2.Text = 'Low:';
            app.HighFreqEditField = uieditfield(app.GridLayout5, 'numeric');
            app.HighFreqEditField.Limits = [0 Inf];
            app.HighFreqEditField.ValueChangedFcn = createCallbackFcn(app, @HighFreqEditFieldValueChanged, true);
            app.HighFreqEditField.Tooltip = {'Only allow single numerical input'};
            app.HighLabel_2 = uilabel(app.GridLayout5);
            app.HighLabel_2.Text = 'High:';
            app.ClumpPanel = uipanel(app.GridLayout2);
            app.ClumpPanel.Title = 'Clump';
            app.GridLayout6 = uigridlayout(app.ClumpPanel);
            app.ClumpEditField = uieditfield(app.GridLayout6, 'numeric');
            app.ClumpEditField.Limits = [0 Inf];
            app.ClumpEditField.ValueChangedFcn = createCallbackFcn(app, @ClumpEditFieldValueChanged, true);
            app.ClumpEditField.Tooltip = {'Only allow single numerical input'};
            app.AcousticComplexityAnalysisPanel = uipanel(app.GridLayout2);
            app.AcousticComplexityAnalysisPanel.Title = 'Acoustic Complexity Analysis';
            app.GridLayout = uigridlayout(app.AcousticComplexityAnalysisPanel);
            app.DoButton = uibutton(app.GridLayout, 'push');
            app.DoButton.ButtonPushedFcn = createCallbackFcn(app, @DoButtonPushed, true);
            app.DoButton.Text = 'Process';
            app.WriteButton = uibutton(app.GridLayout, 'push');
            app.WriteButton.ButtonPushedFcn = createCallbackFcn(app, @WriteButtonPushed, true);
            app.WriteButton.Icon = 'write.png';
            app.WriteButton.Text = 'Save';
            app.NextButton = uibutton(app.GridLayout, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Icon = 'next.gif';
            app.NextButton.Text = 'Next';
            app.EcoacousticEventsButton = uibutton(app.GridLayout, 'push');
            app.EcoacousticEventsButton.ButtonPushedFcn = createCallbackFcn(app, @EcoacousticEventsButtonPushed, true);
            app.EcoacousticEventsButton.Text = 'Ecoacoustic Events';
            app.SSDAnalysisButton = uibutton(app.GridLayout, 'push');
            app.SSDAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SSDAnalysisButtonPushed, true);
            app.SSDAnalysisButton.Text = 'SSD Analysis';
            app.GridLayout7 = uigridlayout(app.GridLayout2);
            app.AutoButton = uibutton(app.GridLayout7, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = 'AUTO';
            app.GridLayout8 = uigridlayout(app.GridLayout2);
            app.AutoFolderButton = uibutton(app.GridLayout8, 'push');
            app.AutoFolderButton.ButtonPushedFcn = createCallbackFcn(app, @AutoFolderButtonPushed, true);
            app.AutoFolderButton.Text = 'AUTO';
            app.ListBox = uilistbox(app.GridLayout2);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.Tree = uitree(app.GridLayout2);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.NodeExpandedFcn = createCallbackFcn(app, @TreeNodeExpanded, true);
            app.FileListLabel = uilabel(app.GridLayout2);
            app.FileListLabel.Text = 'File List';
            app.ExplorerLabel = uilabel(app.GridLayout2);
            app.ExplorerLabel.Text = 'Explorer';
            % Show the figure after all components are created
            app.SonoScapeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SonoScape

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SonoScapeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SonoScapeUIFigure)
        end
    end
end