classdef ezSoundscape < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SoundscapeUIFigure             matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        FolderMenu                     matlab.ui.container.Menu
        OptionsMenu                    matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        StatusLabel                    matlab.ui.control.Label
        ListPanel                      matlab.ui.container.Panel
        ListBox                        matlab.ui.control.ListBox
        ParameterPanel                 matlab.ui.container.Panel
        TimescaleEditFieldLabel        matlab.ui.control.Label
        TimescaleEditField             matlab.ui.control.EditField
        SecLabel                       matlab.ui.control.Label
        AllowexpressionsyntaxLabel     matlab.ui.control.Label
        LowenergyfilterEditFieldLabel  matlab.ui.control.Label
        LowenergyfilterEditField       matlab.ui.control.NumericEditField
        LowfrequencyfilterEditFieldLabel  matlab.ui.control.Label
        LowfrequencyfilterEditField    matlab.ui.control.NumericEditField
        ClumpEditFieldLabel            matlab.ui.control.Label
        ClumpEditField                 matlab.ui.control.NumericEditField
        DoButton                       matlab.ui.control.Button
        AutoButton                     matlab.ui.control.Button
        WriteButton                    matlab.ui.control.Button
        NextButton                     matlab.ui.control.Button
        EcoacousticEventPanel          matlab.ui.container.Panel
        EncodeButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        dataFolder % Description
        files
        
        currentIndex = 1
        currentFile
        
        signal
        Fs
        
        timescale = 1
        lowEnergy = 0
        lowFreq   = 0
        clump     = []
        
        myEco      = ecoacousticAnalysis
        myEcoEvent = ecoacousticEvent
        
        time = struct('format', {'HHmm-ddMMYYYY'}, 'value', [])
        save = struct('ACITotal', 1, 'ACIEvenness', 1, 'ACIIntermediate', 0)
    end
    
    methods (Access = private)
        
        function statusFeed(app, msg, varargin)
            app.StatusLabel.Text = msg;
            
            color = 'k';
            if nargin == 3
                if ~ischar(varargin{1}) && numel(varargin{1}) == 3
                    color = varargin{1};
                else
                    switch varargin{1}
                        case 'warn'
                            color = [1 .325 .286];
                        case {'error', 'r'}
                            color = 'r';
                        case 'reset'
                            color = 'k';
                    end
                end
            end
            app.StatusLabel.FontColor = color;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StatusLabel.Text     = "Initialized";
            app.myEco.timescale      = app.timescale;
            app.myEcoEvent.timescale = app.timescale;
        end

        % Menu selected function: FolderMenu
        function FolderMenuSelected(app, event)
            selpath = uigetdir(fullfile(cd, "*.wav"), "Select Folder Containing Data");
            if selpath == 0
                app.statusFeed("no folder selected", "warn");
            else
                app.dataFolder = selpath;
                allfiles  = dir(fullfile(app.dataFolder, "*.wav"));
                app.files = struct2table(allfiles).name;
                app.ListBox.Items = app.files;
                app.ListBox.ItemsData = 1:length(app.files);
                app.ListBox.Value = 1;
                app.statusFeed("data loaded", "reset");
            end
        end

        % Value changed function: TimescaleEditField
        function TimescaleEditFieldValueChanged(app, event)
            value = app.TimescaleEditField.Value;
            try
                app.timescale = eval(value);
                app.myEco.timescale      = app.timescale;
                app.myEcoEvent.timescale = app.timescale;
                app.statusFeed("Time scale defined", "reset");
            catch
                app.statusFeed("Invalid expression used for time scale!", "error");
            end
        end

        % Value changed function: LowenergyfilterEditField
        function LowenergyfilterEditFieldValueChanged(app, event)
            value = app.LowenergyfilterEditField.Value;
            app.lowEnergy = value;
            app.statusFeed("Filter applied", "reset");
        end

        % Value changed function: LowfrequencyfilterEditField
        function LowfrequencyfilterEditFieldValueChanged(app, event)
            value = app.LowfrequencyfilterEditField.Value;
            app.lowFreq = value;
            app.statusFeed("Filter applied", "reset");
        end

        % Value changed function: ClumpEditField
        function ClumpEditFieldValueChanged(app, event)
            value = app.ClumpEditField.Value;
            if value == 0
                app.clump = [];
            else
                app.clump = value;
            end
            app.statusFeed("Clump applied", "reset");
        end

        % Button pushed function: DoButton
        function DoButtonPushed(app, event)
            app.DoButton.Text = 'Processing ...';
            app.DoButton.IconAlignment = 'bottom';
            
            % create cdata for wait bar
            wbar = permute(repmat(app.DoButton.BackgroundColor, 15, 1, 202), [1, 3, 2]);
            wbar([1 end], :, :) = 0;
            wbar(:, [1 end], :) = 0;
            app.DoButton.Icon = wbar;
            
            % Process
            % Parse data
            if isempty(app.files)
                return;
            end
            
            if app.currentIndex > length(app.files)
                app.statusFeed("Already hit the last file; job's done", "reset");
                return;
            end
            
            app.currentFile = app.files{app.currentIndex};
            [app.signal, app.Fs] = audioread(fullfile(app.dataFolder, app.currentFile));
            drawnow;
            
            % progression 10%
            app.statusFeed("Data loaded", "reset");
            app.DoButton.Icon(2:end-1, 2:21, 1) = .25;
            app.DoButton.Icon(2:end-1, 2:21, 2) = .88;
            app.DoButton.Icon(2:end-1, 2:21, 3) = .41;
            drawnow;
            
            app.myEco.ts = app.signal;
            app.myEco.fs = app.Fs;
            
            app.myEco.window       = hann(1024);
            app.myEco.windowLength = 1024;
            
            app.myEco.acousticComplexity = struct(...
                'minEnergy',      app.lowEnergy, ...
                'cutOffFreqLine', app.lowFreq, ...
                'clumping',       app.clump);
            
            % progression 40%
            app.statusFeed("Wait ...", "reset");
            drawnow;
            tsRescale = app.myEco.rescale;
            app.myEco.stft(tsRescale);
            app.statusFeed("STFT done", "reset");
            app.DoButton.Icon(2:end-1, 22:81, 1) = .25;
            app.DoButton.Icon(2:end-1, 22:81, 2) = .88;
            app.DoButton.Icon(2:end-1, 22:81, 3) = .41;
            drawnow;
            
            % progression 60%
            app.myEco.aci;
            app.statusFeed("ACI done", "reset");
            app.DoButton.Icon(2:end-1, 82:121, 1) = .25;
            app.DoButton.Icon(2:end-1, 82:121, 2) = .88;
            app.DoButton.Icon(2:end-1, 82:121, 3) = .41;
            drawnow;
            
            % progression 100%
            app.myEco.aciEvenness;
            app.myEco.aciTotal
            app.statusFeed("ACI evenness done", "reset");
            app.DoButton.Icon(2:end-1, 122:end-1, 1) = .25;
            app.DoButton.Icon(2:end-1, 122:end-1, 2) = .88;
            app.DoButton.Icon(2:end-1, 122:end-1, 3) = .41;
            drawnow;
            
            % temp max
            app.myEco.aciTotalMax;
            
            % After process
            app.DoButton.Text = 'Done';
            drawnow;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.currentIndex < length(app.files)
                app.currentIndex = app.currentIndex + 1;
                app.ListBox.Value = app.currentIndex;
                
                app.DoButton.Icon = '';
                app.DoButton.Text = 'Process';
                app.statusFeed("File selected", "reset");
            else
                app.currentIndex = length(app.files) + 1;
                app.statusFeed("No more files", "warn");
            end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.currentIndex = value;
            app.statusFeed("File selected", "reset");
            
            app.DoButton.Icon = '';
            app.DoButton.Text = 'Process';
        end

        % Button pushed function: WriteButton
        function WriteButtonPushed(app, event)
            if isempty(app.myEco.aciFTo)
                app.statusFeed("Nothing to save", 'warn');
                return;
            end
            
            app.statusFeed("Saving results ...", "reset");
            drawnow;
            
            savePt = fullfile(app.dataFolder, "Results", app.currentFile(1:end-4));
            if ~(exist(savePt, 'dir') == 7)
                mkdir(savePt);
            end
            
            app.myEco.aciWriteSeperate(savePt);
            app.myEco.aciWriteMax(savePt);
            app.myEco.aciWriteMat(savePt);
            
            % save intermediate
            if app.save.ACIIntermediate
                app.myEco.aciWriteIntermediate(savePt);
            end
            
            app.statusFeed("Results saved", "reset");
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            app.AutoButton.Icon = 'spinner.gif';
            app.AutoButton.IconAlignment = 'bottom';
            
            for iF = app.currentIndex:length(app.files)
                app.DoButtonPushed;
                app.WriteButtonPushed;
                app.NextButtonPushed;
            end
            
            app.statusFeed("Done", "reset");
            app.AutoButton.Icon = '';
        end

        % Button pushed function: EncodeButton
        function EncodeButtonPushed(app, event)
            searchPath = fullfile(app.dataFolder, "Results");
            numFolders = dir(searchPath);
            if numel(numFolders) <= 2
                app.statusFeed("No results found", "warn");
                return;
            end
            app.statusFeed("searching global acitf max ...", "reset");
            tempMaxSt = load(fullfile(searchPath, numFolders(3).name, 'cache.mat'));
            tempMax   = tempMaxSt.aciTToMax__(:);
            failMk = 0;
            for iN = 4:numel(numFolders)
                tempMaxSt = load(fullfile(searchPath, numFolders(iN).name, 'cache.mat'));
                
                try
                    tempMax = max([tempMax tempMaxSt.aciTToMax__(:)], [], 2);
                catch
                    app.statusFeed("folder "+numFolders(iN).name+" seems to be analyzed using different scales!", "error");
                    failMk = 1;
                    break;
                end
            end
            if ~failMk
                app.statusFeed("encoding ...", "reset");
                for iN = 3:numel(numFolders)
                    tempMaxSt = load(fullfile(searchPath, numFolders(iN).name, 'cache.mat'));
                    app.myEcoEvent.aciTTo       = tempMaxSt.aciTTo__;
                    app.myEcoEvent.aciTToMax    = tempMax';
                    app.myEcoEvent.aciTEvenness = tempMaxSt.aciTEvenness__;
                    app.myEcoEvent.aciFEvenness = tempMaxSt.aciFEvenness__;
                    
                    app.myEcoEvent.eeCalc;
                    app.myEcoEvent.eeWrite(fullfile(searchPath, numFolders(iN).name));
                end
                app.statusFeed("done", 'reset')
            end
        end

        % Menu selected function: OptionsMenu
        function OptionsMenuSelected(app, event)
            called = optionWindow;
            app.statusFeed('Waiting for configuration ...');
            uiwait(called.WizardFigure)
            if called.imported
                app.statusFeed("Configuration changed");
                app.time = called.time;
                app.save = called.save;
            else
                app.statusFeed('Nothing changed.');
            end
            
            called.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SoundscapeUIFigure = uifigure('Visible', 'off');
            app.SoundscapeUIFigure.Name = 'Soundscape';
            app.FileMenu = uimenu(app.SoundscapeUIFigure);
            app.FileMenu.Text = '&File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open';
            app.FolderMenu = uimenu(app.OpenMenu);
            app.FolderMenu.MenuSelectedFcn = createCallbackFcn(app, @FolderMenuSelected, true);
            app.FolderMenu.Accelerator = 'F';
            app.FolderMenu.Text = 'Folder';
            app.OptionsMenu = uimenu(app.FileMenu);
            app.OptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @OptionsMenuSelected, true);
            app.OptionsMenu.Separator = 'on';
            app.OptionsMenu.Accelerator = 'O';
            app.OptionsMenu.Text = 'Options';
            app.HelpMenu = uimenu(app.SoundscapeUIFigure);
            app.HelpMenu.Text = '&Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Accelerator = 'A';
            app.AboutMenu.Text = 'About';
            app.StatusLabel = uilabel(app.SoundscapeUIFigure);
            app.StatusLabel.Text = 'Status';
            app.ListPanel = uipanel(app.SoundscapeUIFigure);
            app.ListPanel.Title = 'List:';
            app.ListBox = uilistbox(app.ListPanel);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.ParameterPanel = uipanel(app.SoundscapeUIFigure);
            app.ParameterPanel.Title = 'Parameter:';
            app.TimescaleEditFieldLabel = uilabel(app.ParameterPanel);
            app.TimescaleEditFieldLabel.Text = 'Time scale:';
            app.TimescaleEditField = uieditfield(app.ParameterPanel, 'text');
            app.TimescaleEditField.ValueChangedFcn = createCallbackFcn(app, @TimescaleEditFieldValueChanged, true);
            app.TimescaleEditField.Value = '1';
            app.SecLabel = uilabel(app.ParameterPanel);
            app.SecLabel.Text = 'Sec';
            app.AllowexpressionsyntaxLabel = uilabel(app.ParameterPanel);
            app.AllowexpressionsyntaxLabel.Text = 'Allow expression/syntax';
            app.LowenergyfilterEditFieldLabel = uilabel(app.ParameterPanel);
            app.LowenergyfilterEditFieldLabel.Text = 'Low energy filter:';
            app.LowenergyfilterEditField = uieditfield(app.ParameterPanel, 'numeric');
            app.LowenergyfilterEditField.Limits = [0 Inf];
            app.LowenergyfilterEditField.ValueChangedFcn = createCallbackFcn(app, @LowenergyfilterEditFieldValueChanged, true);
            app.LowfrequencyfilterEditFieldLabel = uilabel(app.ParameterPanel);
            app.LowfrequencyfilterEditFieldLabel.Text = 'Low frequency filter:';
            app.LowfrequencyfilterEditField = uieditfield(app.ParameterPanel, 'numeric');
            app.LowfrequencyfilterEditField.Limits = [0 Inf];
            app.LowfrequencyfilterEditField.ValueChangedFcn = createCallbackFcn(app, @LowfrequencyfilterEditFieldValueChanged, true);
            app.ClumpEditFieldLabel = uilabel(app.ParameterPanel);
            app.ClumpEditFieldLabel.Text = 'Clump:';
            app.ClumpEditField = uieditfield(app.ParameterPanel, 'numeric');
            app.ClumpEditField.Limits = [0 Inf];
            app.ClumpEditField.ValueChangedFcn = createCallbackFcn(app, @ClumpEditFieldValueChanged, true);
            app.DoButton = uibutton(app.SoundscapeUIFigure, 'push');
            app.DoButton.ButtonPushedFcn = createCallbackFcn(app, @DoButtonPushed, true);
            app.DoButton.Text = 'Process';
            app.AutoButton = uibutton(app.SoundscapeUIFigure, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = 'AUTO';
            app.WriteButton = uibutton(app.SoundscapeUIFigure, 'push');
            app.WriteButton.ButtonPushedFcn = createCallbackFcn(app, @WriteButtonPushed, true);
            app.WriteButton.Icon = 'write.png';
            app.WriteButton.Text = 'Save';
            app.NextButton = uibutton(app.SoundscapeUIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Icon = 'next.gif';
            app.NextButton.Text = 'Next';
            app.EcoacousticEventPanel = uipanel(app.SoundscapeUIFigure);
            app.EcoacousticEventPanel.Title = 'Ecoacoustic Event';
            app.EncodeButton = uibutton(app.EcoacousticEventPanel, 'push');
            app.EncodeButton.ButtonPushedFcn = createCallbackFcn(app, @EncodeButtonPushed, true);
            app.EncodeButton.Icon = 'parameter.gif';
            app.EncodeButton.Text = 'Encode';
            % Show the figure after all components are created
            app.SoundscapeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ezSoundscape

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SoundscapeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SoundscapeUIFigure)
        end
    end
end