classdef patternRecognize < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PatternRecognizeUIFigure  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        AcousticsignalPanel       matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        SignalUIAxes              matlab.ui.control.UIAxes
        PearsonUIAxes             matlab.ui.control.UIAxes
        PatternPanel              matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        DefinePatternButton       matlab.ui.control.Button
        RecognizeButton           matlab.ui.control.Button
        PatSignalUIAxes           matlab.ui.control.UIAxes
        PatACIUIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        signal
        x
        fs
        
        patName
        patSignal
        patFs
        
        patACItf
    end
    
    properties (Access = private)
        filePath
        fileName
        
        aciPara
        
        matched = struct('data', [], 'handle', [], 'text', [])
    end
    
    properties (Constant = true)
        scale = 1
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, filename, pathname, aciparameter)
            app.filePath = pathname;
            app.fileName = filename;
            
            [app.signal, app.fs] = audioread(fullfile(pathname, filename));
            xs = (0:length(app.signal)-1)/app.fs;
            
            plot(app.SignalUIAxes, xs, app.signal, 'Color', 'k');
            set(app.SignalUIAxes, 'XLim', [xs(1) round(xs(end))], 'Box', 'off');
            
            app.aciPara = aciparameter;
        end

        % Button pushed function: DefinePatternButton
        function DefinePatternButtonPushed(app, event)
            [filename, filepath] = uigetfile('*.wav', 'Select a file ...', 'MultiSelect', "off");
            
            if isequal(filename, 0)
                return;
            end
            
            app.PearsonUIAxes.NextPlot = 'replace';
            cla(app.PearsonUIAxes);
            
            app.patName = filename(1:end-4);
            
            [app.patSignal, app.patFs] = audioread(fullfile(filepath, filename));
            app.x = (0:length(app.patSignal)-1)/app.patFs;
            
            plot(app.PatSignalUIAxes, app.x, app.patSignal, 'Color', 'b');
            set(app.PatSignalUIAxes, 'XLim', [app.x(1) round(app.x(end))], ...
                'Box', 'on', 'Layer', 'top');
            
            drawnow;
            
            % aci of pattern
            myEco    = ecoacousticAnalysis;
            myEco.ts = app.patSignal;
            myEco.fs = app.patFs;
            
            myEco.timescale    = app.scale;
            myEco.window       = hann(1024);
            myEco.windowLength = 1024;
            
            myEco.acousticComplexity = app.aciPara;
            
            myEco.ecoAna;
            
            app.patACItf = myEco.aciF{1};
            
            % visualize pattern aci
            aciTf = app.patACItf;
            waterfall(app.PatACIUIAxes, 1:size(aciTf, 1), 1:size(aciTf, 2), aciTf');
            set(app.PatACIUIAxes.Children, 'FaceColor', 'none', 'LineWidth', 1);
            colormap(app.PatACIUIAxes, 'jet');
            
            app.PatACIUIAxes.View = [-15, 60];
            app.PatACIUIAxes.YLim =  [0 size(aciTf, 2)+1];
            
            zlabel(app.PatACIUIAxes, 'ACI_{Tf} (scale: 1)');
            
            set(app.PatACIUIAxes, 'YTick', 0:app.PatSignalUIAxes.XLim(2), ...
                'XTick', 0:128:512, 'XLim', [0 512]);
            
            grid(app.PatACIUIAxes, 'on');
        end

        % Button pushed function: RecognizeButton
        function RecognizeButtonPushed(app, event)
            if isempty(app.patACItf)
                return;
            end
            
            destPath  = fullfile(app.filePath, 'Results', app.fileName(1:end-4), "Pattern_"+app.patName);
            
            cacheFile = fullfile(app.filePath, 'Results', app.fileName(1:end-4), 'cache.mat');
            if exist(cacheFile, 'file') == 2
                res = load(cacheFile);
                if any(res.aciTimescale__ == app.scale)
                    searchACITf = res.aciF__{res.aciTimescale__ == app.scale};
                    pearson = zeros(size(searchACITf, 2) - size(app.patACItf, 2) + 1, 1);
                    for iP  = 1:numel(pearson)
                        tp  = 1;
                        for iT = 1:size(app.patACItf, 2)
                            p  = corrcoef(app.patACItf(:, iT), searchACITf(:, iP+iT-1));
                            tp = tp * p(2);
                        end
                        pearson(iP) = tp;
                    end
                    plot(app.PearsonUIAxes, 0:numel(pearson)-1, pearson, 'k', 'LineWidth', 2);
                    set(app.PearsonUIAxes, 'XLim', app.SignalUIAxes.XLim, 'NextPlot', 'add');
                    plot(app.PearsonUIAxes, ...
                        app.PearsonUIAxes.XLim, [0.95^size(app.patACItf, 2) 0.95^size(app.patACItf, 2)], 'b:');
                    
                    thre = pearson >= 0.95^size(app.patACItf, 2);
                    
                    incl = thre == 1;
                    incT = diff([0; incl; 0]);
                    stId = find(incT == 1);
                    edId = find(incT == -1) - 1;
                    app.matched.data  = [stId(:) edId(:)];
                    
                    updateItem(app);
                    app.PearsonUIAxes.XLabel.String = 'Time (sec)';
                    app.PearsonUIAxes.YLabel.String = 'Pearson';
                end
            end
            
            function updateItem(app)
                epi    = app.matched.data;
                Yrange = app.SignalUIAxes.YLim;
                
                % update plot
                if ~isempty(app.matched.handle)
                    delete(app.matched.handle);
                    app.matched.handle = [];
                end
                
                if ~isempty(app.matched.text)
                    delete(app.matched.text);
                    app.matched.text = [];
                end
                for iG = 1:size(epi, 1)
                    app.matched.handle(iG) = patch('Parent', app.SignalUIAxes, 'XData', app.x([epi(iG, 1) epi(iG, 1) app.fs*(epi(iG, 2)+size(app.patACItf, 2)-1) app.fs*(epi(iG, 2)+size(app.patACItf, 2)-1)]), ...
                        'YData', [Yrange(1) Yrange(2) Yrange(2) Yrange(1)], 'FaceColor', 'm', 'EdgeColor', 'm', 'FaceAlpha', 0.2);
                    app.matched.text(iG) = text(app.SignalUIAxes, epi(iG, 1), Yrange(2) - ((iG-1)./size(epi, 1))*diff(Yrange), ['matched: # ' num2str(iG)], 'Color', 'm');
                end
                
                
                % switch layers
                child = app.SignalUIAxes.Children;
                child = [child(end-1:end); child(1)];
                app.SignalUIAxes.Children = child;
                
                drawnow;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PatternRecognizeUIFigure = uifigure('Visible', 'off');
            app.PatternRecognizeUIFigure.Name = 'Pattern Recognize';
            app.GridLayout = uigridlayout(app.PatternRecognizeUIFigure);
            app.AcousticsignalPanel = uipanel(app.GridLayout);
            app.AcousticsignalPanel.Title = 'Acoustic signal';
            app.GridLayout2 = uigridlayout(app.AcousticsignalPanel);
            app.SignalUIAxes = uiaxes(app.GridLayout2);
            xlabel(app.SignalUIAxes, 'Time (sec)')
            ylabel(app.SignalUIAxes, 'Y')
            zlabel(app.SignalUIAxes, 'Z')
            app.PearsonUIAxes = uiaxes(app.GridLayout2);
            xlabel(app.PearsonUIAxes, 'Time (sec)')
            ylabel(app.PearsonUIAxes, 'Pearson')
            zlabel(app.PearsonUIAxes, 'Z')
            app.PatternPanel = uipanel(app.GridLayout);
            app.PatternPanel.Title = 'Pattern';
            app.GridLayout3 = uigridlayout(app.PatternPanel);
            app.DefinePatternButton = uibutton(app.GridLayout3, 'push');
            app.DefinePatternButton.ButtonPushedFcn = createCallbackFcn(app, @DefinePatternButtonPushed, true);
            app.DefinePatternButton.Text = 'Define Pattern';
            app.RecognizeButton = uibutton(app.GridLayout3, 'push');
            app.RecognizeButton.ButtonPushedFcn = createCallbackFcn(app, @RecognizeButtonPushed, true);
            app.RecognizeButton.Text = 'Recognize';
            app.PatSignalUIAxes = uiaxes(app.GridLayout3);
            xlabel(app.PatSignalUIAxes, 'Time (sec)')
            ylabel(app.PatSignalUIAxes, 'Y')
            zlabel(app.PatSignalUIAxes, 'Z')
            app.PatACIUIAxes = uiaxes(app.GridLayout3);
            xlabel(app.PatACIUIAxes, 'freq line')
            ylabel(app.PatACIUIAxes, 'time step')
            zlabel(app.PatACIUIAxes, 'Z')
            % Show the figure after all components are created
            app.PatternRecognizeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = patternRecognize(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PatternRecognizeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PatternRecognizeUIFigure)
        end
    end
end